classdef interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MOVERCONTXTButton               matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        ConexionNode                    matlab.ui.container.TreeNode
        ArduinoNode                     matlab.ui.container.TreeNode
        RaspberryNode                   matlab.ui.container.TreeNode
        GradodeiterpolacinEditField     matlab.ui.control.EditField
        GradodeiterpolacinEditFieldLabel  matlab.ui.control.Label
        NmerodepuntosagenerarEditField  matlab.ui.control.EditField
        NmerodepuntosagenerarEditFieldLabel  matlab.ui.control.Label
        IngresaelpuntoenZEditField      matlab.ui.control.EditField
        IngresaelpuntoenZEditFieldLabel  matlab.ui.control.Label
        IngresaelpuntoenYEditField      matlab.ui.control.EditField
        IngresaelpuntoenYEditFieldLabel  matlab.ui.control.Label
        IngresaelpuntoenXEditField      matlab.ui.control.EditField
        IngresaelpuntoenXEditFieldLabel  matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        UsarxlsxButton                  matlab.ui.control.Button
        MOVERROBOTButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CalcularpuntosButton            matlab.ui.control.Button
        AgregarButton                   matlab.ui.control.Button
        PLANIFICACINDETRAYECTORIADEROBOTDE5GDLLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X = []; % Almacenar coordenadas X
        Y = []; % Almacenar coordenadas Y
        Z = []; % Almacenar coordenadas Z
        q_matrix = [];
        interp_points = [];
        qmatrix_trans = [];
        editF1_a = 0;
        editF2_a = 0;
        editF1_r = 0;
        editF2_r = 0;
        editF3_r = 0;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = [1 0 0];

        end

        % Clicked callback: Tree
        function TreeClicked(app, event)
            node = event.InteractionInformation.Node;
            if string(node.Text) == "Arduino"
                app.editF1_a=uieditfield(app.UIFigure,"text","Position",[190 650 100 22]);
                label1_a=uilabel(app.UIFigure,"Text",'Ingresa el Puerto COM',"Position",[30 650 150 22],"FontSize",14);

                app.editF2_a=uieditfield(app.UIFigure,"text","Position",[190 620 100 22]);
                label2_a=uilabel(app.UIFigure,"Text",'Ingresa el dispositivo',"Position",[30 620 150 22],"FontSize",14);

            elseif string(node.Text) == "Raspberry"
                app.editF1_r=uieditfield(app.UIFigure,"text","Position",[290 650 100 22]);
                label1_r=uilabel(app.UIFigure,"Text",'Ingresa la IP',"Position",[30 650 150 22],"FontSize",14);

                app.editF2_r=uieditfield(app.UIFigure,"text","Position",[290 620 100 22]);
                label2_r=uilabel(app.UIFigure,"Text",'Ingresa el nombre del anfitrión',"Position",[30 620 250 22],"FontSize",14);

                app.editF3_r=uieditfield(app.UIFigure,"text","Position",[290 590 100 22]);
                label3_r=uilabel(app.UIFigure,"Text",'Ingresa la contraseña',"Position",[30 590 150 22],"FontSize",14);

            end

        end

        % Button pushed function: CalcularpuntosButton
        function CalcularpuntosButtonPushed(app, event)
            app.interp_points = str2num(app.NmerodepuntosagenerarEditField.Value); % interpolacion
            degree = str2num(app.GradodeiterpolacinEditField.Value); % interpolacion
            num_points = size(app.X,2); % sin interpolacion
            
            % Interpolación
            t = linspace(0, 1, num_points); % Parámetro t para los puntos originales
            ti = linspace(0, 1, app.interp_points); % Parámetro t para los puntos interpolados
            
            % Interpolación en cada dimensión
            % Polinomial
            pX = polyfit(t, app.X, degree);
            pY = polyfit(t, app.Y, degree);
            pZ = polyfit(t, app.Z, degree);
            Xi = polyval(pX, ti);
            Yi = polyval(pY, ti);
            Zi = polyval(pZ, ti);
                
            % Dibujar la curva original y la interpolada
            graf=app.UIAxes;
            plot3(graf,app.X, app.Y, app.Z, 'o-', 'LineWidth', 1.5, 'DisplayName', 'Puntos Originales');
            hold(graf,"on");
            plot3(graf,Xi, Yi, Zi, 'r-', 'LineWidth', 2, 'DisplayName', 'Interpolación');
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            zlabel(app.UIAxes,'Z');
            title(app.UIAxes,'Interpolación de Curva 3D');
            hold(graf,"off");

            % Guardar puntos interpolados
            disp('Puntos calculados de la interpolación:');
            interp_result = [Xi; Yi; Zi]';
            disp(interp_result);
            
            % Supongamos que los vectores son:
            p_x = Xi; 
            p_y = Yi; 
            p_z = Zi; 
            phi = ones(1, app.interp_points)*3.14159;   % Ángulos aleatorios entre 0 y pi
            theta = ones(1, app.interp_points)*-3.14159/2;
            psi = ones(1, app.interp_points)*0;
            
            % Matriz para almacenar los resultados de los ángulos
            app.q_matrix = zeros(app.interp_points,5);
            
            % Bucle para calcular los ángulos para cada conjunto de valores
            for i = 1:app.interp_points
                % Ejecutar la función con los valores correspondientes
                angulos = cinematica_inv(0.1920, 0.1820, 0.2020, 0.1238, 0.0950, ...
                                          p_x(i), p_y(i), p_z(i), ...
                                          double(phi(i)), double(theta(i)), double(psi(i)));
                % Almacenar los resultados en la matriz
                app.q_matrix(i, :) = angulos;
            end
            
            % Visualizar la matriz de ángulos resultante
            disp('Matriz de ángulos (q1, q2, q3, q4, q5):');
            disp(app.q_matrix);
            app.qmatrix_trans=app.q_matrix.';

            app.UITable.Data=[app.q_matrix(:,1),app.q_matrix(:,2),app.q_matrix(:,3),app.q_matrix(:,4),app.q_matrix(:,5)];

            %% GUARDAR DATOS:
            j=1:1:app.interp_points;
            k=j';

            figure

            subplot(1,5,1)

            plot(j,app.qmatrix_trans(1,:).','b')
            title('Motor_1');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,2)

            plot(j,app.qmatrix_trans(2,:).','r')
            title('Motor_2');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,3)

            plot(j,app.qmatrix_trans(3,:).','g')
            title('Motor_3');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');
    
            subplot(1,5,4)

            plot(j,app.qmatrix_trans(4,:).','m')
            title('Motor_4');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,5)

            plot(j,app.qmatrix_trans(5,:).','Color',[0.1 0.5 0.7])
            title('Motor_5');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');
 
            q1_final=zeros(app.interp_points,2);
            q2_final=zeros(app.interp_points,2);
            q3_final=zeros(app.interp_points,2);
            q4_final=zeros(app.interp_points,2);
            q5_final=zeros(app.interp_points,2);

            for j=1:1:app.interp_points
                q1_final(j,:)=[k(j) app.q_matrix(j,1)];
                q2_final(j,:)=[k(j) app.q_matrix(j,2)];
                q3_final(j,:)=[k(j) app.q_matrix(j,3)];
                q4_final(j,:)=[k(j) app.q_matrix(j,4)];
                q5_final(j,:)=[k(j) app.q_matrix(j,5)];

            end

            writematrix(q1_final,'articulacionq1.txt')
            writematrix(q2_final,'articulacionq2.txt')
            writematrix(q3_final,'articulacionq3.txt')
            writematrix(q4_final,'articulacionq4.txt')
            writematrix(q5_final,'articulacionq5.txt')

        end

        % Button pushed function: MOVERROBOTButton
        function MOVERROBOTButtonPushed(app, event)
            % ARDUINO
            COM = char(app.editF1_a.Value);
            DISP = char(app.editF2_a.Value);
            
            if string(app.Tree.SelectedNodes.Text) == "Arduino"
                ard = arduino(COM,DISP,'Libraries','Servo');
                disp("Arduino conectado")
                app.Lamp.Color = [0 1 0];
                
                PaP = servo(ard,'D6','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_1 = servo(ard,'D3','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_2 = servo(ard,'D5','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_3 = servo(ard,'D9','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_4 = servo(ard,'D10','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_5 = servo(ard,'D11','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                
                writeDigitalPin(ard,"D2",1) % Enable Pin
                writeDigitalPin(ard,"D4",0) % Direction Pin

                % PaP = configurePin(ard,'D11','PWM');
                % Servo_1 = configurePin(ard,'D10','PWM');
                % Servo_2 = configurePin(ard,'D6','PWM');
                % Servo_3 = configurePin(ard,'D5','PWM');
                % Servo_4 = configurePin(ard,'D3','PWM');

                % Mapear valores
                transformados1 = rescale(app.qmatrix_trans(1,:), 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados1(app.qmatrix_trans(1,:) >= 180) = 1; % Asignar 1 a valores >= 180

                transformados2 = rescale(app.qmatrix_trans(2,:), 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados2(app.qmatrix_trans(2,:) >= 180) = 1; % Asignar 1 a valores >= 180
    
                transformados3 = rescale(app.qmatrix_trans(3,:), 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados3(app.qmatrix_trans(3,:) >= 180) = 1; % Asignar 1 a valores >= 180
    
    
                transformados4 = rescale(app.qmatrix_trans(4,:), 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados4(app.qmatrix_trans(4,:) >= 180) = 1; % Asignar 1 a valores >= 180
    
                transformados5 = rescale(app.qmatrix_trans(5,:), 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados5(app.qmatrix_trans(5,:) >= 180) = 1; % Asignar 1 a valores >= 180
                
                for i=1:1:app.interp_points
                    
                    writePosition(PaP,transformados1(i))
                    writePosition(Servo_1,transformados2(i))
                    writePosition(Servo_2,transformados3(i))
                    writePosition(Servo_3,transformados4(i))
                    writePosition(Servo_4,transformados5(i))
                    pause(0.4)
                end
                
                app.Lamp.Color = [1 0 0];
                writeDigitalPin(ard, "D2",0) % Enable Pin
                
            % RASPBERYY
            elseif string(app.Tree.SelectedNodes.Text) == "Raspberry"
                myPi = raspi(app.editF1_a.Value,app.editF2_a.Value,app.editF3_a.Value);
            
            end

        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
        X_temp = str2num(app.IngresaelpuntoenXEditField.Value);
        Y_temp = str2num(app.IngresaelpuntoenYEditField.Value);
        Z_temp = str2num(app.IngresaelpuntoenZEditField.Value);

        app.X(1,end+1)=X_temp;
        app.Y(1,end+1)=Y_temp;
        app.Z(1,end+1)=Z_temp;

        app.IngresaelpuntoenXEditField.Value = '';
        app.IngresaelpuntoenYEditField.Value = '';
        app.IngresaelpuntoenZEditField.Value = '';

        end

        % Button pushed function: UsarxlsxButton
        function UsarxlsxButtonPushed(app, event)
            P=readmatrix('P.xlsx'); 
            nnn = size(P,1);

            j=1:1:nnn;  % se crea un vector de 1 a 74 con paso 1
                app.X(j)=P(j,1); % primera columna de P almacenamos en X 
                app.Y(j)=P(j,2); % segunda columna de P almacenamos en Y
                app.Z(j)=P(j,3); % tercera columna de P almacenamos en Z

            app.interp_points = str2num(app.NmerodepuntosagenerarEditField.Value); % interpolacion
            degree = str2num(app.GradodeiterpolacinEditField.Value); % interpolacion
            num_points = size(app.X,2); % sin interpolacion
            
            % Interpolación
            t = linspace(0, 1, num_points); % Parámetro t para los puntos originales
            ti = linspace(0, 1, app.interp_points); % Parámetro t para los puntos interpolados
            
            % Interpolación en cada dimensión
            % Polinomial
            pX = polyfit(t, app.X, degree);
            pY = polyfit(t, app.Y, degree);
            pZ = polyfit(t, app.Z, degree);
            Xi = polyval(pX, ti);
            Yi = polyval(pY, ti);
            Zi = polyval(pZ, ti);
                
            % Dibujar la curva original y la interpolada
            graf=app.UIAxes;
            plot3(graf,app.X, app.Y, app.Z, 'o-', 'LineWidth', 1.5, 'DisplayName', 'Puntos Originales');
            hold(graf,"on");
            plot3(graf,Xi, Yi, Zi, 'r-', 'LineWidth', 2, 'DisplayName', 'Interpolación');
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            zlabel(app.UIAxes,'Z');
            title(app.UIAxes,'Interpolación de Curva 3D');
            hold(graf,"off");

            % Guardar puntos interpolados
            disp('Puntos calculados de la interpolación:');
            interp_result = [Xi; Yi; Zi]';
            disp(interp_result);
            
            % Supongamos que los vectores son:
            
            p_x = Xi; % Ejemplo de datos
            p_y = Yi; 
            p_z = Zi; 
            phi = ones(1, app.interp_points)*3.14159;   % Ángulos aleatorios entre 0 y pi
            theta = ones(1, app.interp_points)*-3.14159/2;
            psi = ones(1, app.interp_points)*0;
            
            % Matriz para almacenar los resultados de los ángulos
            app.q_matrix = zeros(app.interp_points,5);
            
            % Bucle para calcular los ángulos para cada conjunto de valores
            for i = 1:app.interp_points
                % Ejecutar la función con los valores correspondientes
                angulos = cinematica_inv(0.1920, 0.1820, 0.2020, 0.1238, 0.0950, ...
                                          p_x(i), p_y(i), p_z(i), ...
                                          double(phi(i)), double(theta(i)), double(psi(i)));
                % Almacenar los resultados en la matriz
                app.q_matrix(i, :) = angulos;
            end
            
            % Visualizar la matriz de ángulos resultante
            disp('Matriz de ángulos (q1, q2, q3, q4, q5):');
            disp(app.q_matrix);
            app.qmatrix_trans=app.q_matrix.';

            app.UITable.Data=[app.q_matrix(:,1),app.q_matrix(:,2),app.q_matrix(:,3),app.q_matrix(:,4),app.q_matrix(:,5)];

            %% GUARDAR DATOS:
            j=1:1:app.interp_points;
            k=j';

            figure

            subplot(1,5,1)

            plot(j,app.qmatrix_trans(1,:).','b')
            title('Motor_1');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,2)

            plot(j,app.qmatrix_trans(2,:).','r')
            title('Motor_2');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,3)

            plot(j,app.qmatrix_trans(3,:).','g')
            title('Motor_3');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');
    
            subplot(1,5,4)

            plot(j,app.qmatrix_trans(4,:).','m')
            title('Motor_4');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');

            subplot(1,5,5)

            plot(j,app.qmatrix_trans(5,:).','Color',[0.1 0.5 0.7])
            title('Motor_5');
            xlabel('tiempo(seg)');
            ylabel('angulo(grados°)');
 
            q1_final=zeros(app.interp_points,2);
            q2_final=zeros(app.interp_points,2);
            q3_final=zeros(app.interp_points,2);
            q4_final=zeros(app.interp_points,2);
            q5_final=zeros(app.interp_points,2);

            for j=1:1:app.interp_points
                q1_final(j,:)=[k(j) app.q_matrix(j,1)];
                q2_final(j,:)=[k(j) app.q_matrix(j,2)];
                q3_final(j,:)=[k(j) app.q_matrix(j,3)];
                q4_final(j,:)=[k(j) app.q_matrix(j,4)];
                q5_final(j,:)=[k(j) app.q_matrix(j,5)];

            end

            writematrix(q1_final,'articulacionq1.txt')
            writematrix(q2_final,'articulacionq2.txt')
            writematrix(q3_final,'articulacionq3.txt')
            writematrix(q4_final,'articulacionq4.txt')
            writematrix(q5_final,'articulacionq5.txt')
        end

        % Button pushed function: MOVERCONTXTButton
        function MOVERCONTXTButtonPushed(app, event)
            Pq1=readmatrix('articulacionq1.txt'); 
            Pq2=readmatrix('articulacionq2.txt'); 
            Pq3=readmatrix('articulacionq3.txt'); 
            Pq4=readmatrix('articulacionq4.txt'); 
            Pq5=readmatrix('articulacionq5.txt'); 

            nnn = size(Pq5,1);

            j=1:1:nnn; 
            q1_n(j)=Pq1(j,2); 
            q2_n(j)=Pq2(j,2); 
            q3_n(j)=Pq3(j,2); 
            q4_n(j)=Pq4(j,2);
            q5_n(j)=Pq5(j,2);

            % ARDUINO
            COM = char(app.editF1_a.Value);
            DISP = char(app.editF2_a.Value);
            
            if string(app.Tree.SelectedNodes.Text) == "Arduino"
                ard = arduino(COM,DISP,'Libraries','Servo');
                disp("Arduino conectado")
                app.Lamp.Color = [0 1 0];
                
                PaP = servo(ard,'D6','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_1 = servo(ard,'D3','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_2 = servo(ard,'D5','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_3 = servo(ard,'D9','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_4 = servo(ard,'D10','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                Servo_5 = servo(ard,'D11','MinPulseDuration',0.5e-3,'MaxPulseDuration',2.5e-3);
                
                writeDigitalPin(ard,"D2",1) % Enable Pin
                writeDigitalPin(ard,"D4",0) % Direction Pin

                % PaP = configurePin(ard,'D11','PWM');
                % Servo_1 = configurePin(ard,'D10','PWM');
                % Servo_2 = configurePin(ard,'D6','PWM');
                % Servo_3 = configurePin(ard,'D5','PWM');
                % Servo_4 = configurePin(ard,'D3','PWM');

                % Mapear valores
                transformados1 = rescale(q1_n, 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados1(q1_n >= 180) = 1; % Asignar 1 a valores >= 180

                transformados2 = rescale(q2_n, 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados2(q2_n >= 180) = 1; % Asignar 1 a valores >= 180
    
                transformados3 = rescale(q3_n, 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados3(q3_n >= 180) = 1; % Asignar 1 a valores >= 180
    
                transformados4 = rescale(q4_n, 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados4(q4_n >= 180) = 1; % Asignar 1 a valores >= 180
    
                transformados5 = rescale(q5_n, 0, 1, 'InputMin', 0, 'InputMax', 180); % Escalar de 0 a 180
                transformados5(q5_n >= 180) = 1; % Asignar 1 a valores >= 180
                disp(transformados1)
                disp(transformados2)
                disp(transformados3)
                disp(transformados4)
                disp(transformados5)
                disp(app.interp_points)

                for i=1:1:20
                    
                    writePosition(PaP,transformados1(i))
                    writePosition(Servo_1,transformados2(i))
                    writePosition(Servo_2,transformados3(i))
                    writePosition(Servo_3,transformados4(i))
                    writePosition(Servo_4,transformados5(i))
                    pause(2)
                end
                
                app.Lamp.Color = [1 0 0];
                writeDigitalPin(ard, "D2",0) % Enable Pin
                
            % RASPBERYY
            elseif string(app.Tree.SelectedNodes.Text) == "Raspberry"
                myPi = raspi(app.editF1_a.Value,app.editF2_a.Value,app.editF3_a.Value);
            
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PLANIFICACINDETRAYECTORIADEROBOTDE5GDLLabel = uilabel(app.UIFigure);
            app.PLANIFICACINDETRAYECTORIADEROBOTDE5GDLLabel.Text = 'PLANIFICACIÓN DE TRAYECTORIA DE ROBOT DE 5GDL';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.CalcularpuntosButton = uibutton(app.UIFigure, 'push');
            app.CalcularpuntosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularpuntosButtonPushed, true);
            app.CalcularpuntosButton.Text = 'Calcular puntos';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'q1'; 'q2'; 'q3'; 'q4'; 'q5'};
            app.UITable.RowName = {};
            app.MOVERROBOTButton = uibutton(app.UIFigure, 'push');
            app.MOVERROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @MOVERROBOTButtonPushed, true);
            app.MOVERROBOTButton.Text = 'MOVER ROBOT';
            app.UsarxlsxButton = uibutton(app.UIFigure, 'push');
            app.UsarxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @UsarxlsxButtonPushed, true);
            app.UsarxlsxButton.Text = 'Usar xlsx';
            app.Lamp = uilamp(app.UIFigure);
            app.IngresaelpuntoenXEditFieldLabel = uilabel(app.UIFigure);
            app.IngresaelpuntoenXEditFieldLabel.Text = 'Ingresa el punto en X';
            app.IngresaelpuntoenXEditField = uieditfield(app.UIFigure, 'text');
            app.IngresaelpuntoenYEditFieldLabel = uilabel(app.UIFigure);
            app.IngresaelpuntoenYEditFieldLabel.Text = 'Ingresa el punto en Y';
            app.IngresaelpuntoenYEditField = uieditfield(app.UIFigure, 'text');
            app.IngresaelpuntoenZEditFieldLabel = uilabel(app.UIFigure);
            app.IngresaelpuntoenZEditFieldLabel.Text = 'Ingresa el punto en Z';
            app.IngresaelpuntoenZEditField = uieditfield(app.UIFigure, 'text');
            app.NmerodepuntosagenerarEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodepuntosagenerarEditFieldLabel.Text = 'Número de puntos a generar';
            app.NmerodepuntosagenerarEditField = uieditfield(app.UIFigure, 'text');
            app.GradodeiterpolacinEditFieldLabel = uilabel(app.UIFigure);
            app.GradodeiterpolacinEditFieldLabel.Text = 'Grado de iterpolación';
            app.GradodeiterpolacinEditField = uieditfield(app.UIFigure, 'text');
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.ClickedFcn = createCallbackFcn(app, @TreeClicked, true);
            app.ConexionNode = uitreenode(app.Tree);
            app.ConexionNode.Text = 'Conexion';
            app.ArduinoNode = uitreenode(app.ConexionNode);
            app.ArduinoNode.Text = 'Arduino';
            app.RaspberryNode = uitreenode(app.ConexionNode);
            app.RaspberryNode.Text = 'Raspberry';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.ArduinoNode, app.RaspberryNode, app.ConexionNode];
            app.MOVERCONTXTButton = uibutton(app.UIFigure, 'push');
            app.MOVERCONTXTButton.ButtonPushedFcn = createCallbackFcn(app, @MOVERCONTXTButtonPushed, true);
            app.MOVERCONTXTButton.Text = 'MOVER CON TXT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end